classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ZoomingTab                  matlab.ui.container.Tab
        Zoom                        matlab.ui.container.Panel
        ZoomedImage                 matlab.ui.control.Image
        Original_2                  matlab.ui.container.Panel
        OrImage_2                   matlab.ui.control.Image
        FactorEditFieldLabel        matlab.ui.control.Label
        FactorEditField             matlab.ui.control.NumericEditField
        AlgorithmsListBoxLabel_2    matlab.ui.control.Label
        AlgorithmsListBoxpp_2       matlab.ui.control.ListBox
        performButton_2             matlab.ui.control.Button
        ImagesLabel_2               matlab.ui.control.Label
        Images_2                    matlab.ui.control.ListBox
        Label                       matlab.ui.control.Label
        HistogramsTab               matlab.ui.container.Tab
        AlgorithmsListBoxLabel      matlab.ui.control.Label
        AlgorithmsListBoxpp         matlab.ui.control.ListBox
        performButton               matlab.ui.control.Button
        HisOriginal                 matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        Orignal                     matlab.ui.container.Panel
        OrImage                     matlab.ui.control.Image
        HisModified                 matlab.ui.container.Panel
        UIAxes_2                    matlab.ui.control.UIAxes
        Modified                    matlab.ui.container.Panel
        ModifiedImage               matlab.ui.control.Image
        ImagesLabel                 matlab.ui.control.Label
        Images                      matlab.ui.control.ListBox
        BrightnessEditField_2Label  matlab.ui.control.Label
        BrightnessEditField         matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: performButton
        function performButtonPushed(app, event)
            i=app.BrightnessEditField.Value;
            switch app.AlgorithmsListBoxpp.Value
                case "Brightness Histogram"
                    [out , readIn , readOut ]=Brightnessfunc(app.BrightnessEditField.Value,app.OrImage.ImageSource,"new"+i+app.OrImage.ImageSource);            
                    
                    case "Equalization Histogram"
                    [out , readIn , readOut ]=Equalizefunc(app.OrImage.ImageSource,"new"+app.OrImage.ImageSource);
                  
                    case "Streching Histohram"
                    [out , readIn , readOut ]=Stertchingfunc(app.OrImage.ImageSource,"new"+app.OrImage.ImageSource);
                    
            end
            
             app.ModifiedImage.ImageSource=out; 
            SHOW(readIn , readOut , app.UIAxes , app.UIAxes_2);
        end

        % Value changed function: Images
        function ImagesValueChanged(app, event)
            value = app.Images.Value;
            app.OrImage.ImageSource=value;
           
        end

        % Button pushed function: performButton_2
        function performButton_2Pushed(app, event)
            i=app.FactorEditField.Value;
            app.Label.Text="";
            switch app.AlgorithmsListBoxpp_2.Value
                case "K-times"
                    out=KTimesfunc(app.FactorEditField.Value,app.Images_2.Value,"new"+i+app.OrImage_2.ImageSource);
                    app.ZoomedImage.ImageSource=out;
                    
                    case "Replication"
                    if app.FactorEditField.Value ~= 1
                        out=Replicationfunc(app.FactorEditField.Value,app.Images_2.Value,"new"+i+app.OrImage_2.ImageSource);
                        app.ZoomedImage.ImageSource=out;
                    else
                        app.ZoomedImage.ImageSource=app.Images_2.Value;
                    end
                    
                    
                    case "ZeroHold"
                    if mod(app.FactorEditField.Value,2)==0 
                    out=ZeroHoldfunc(app.FactorEditField.Value,app.Images_2.Value,"new"+i+app.OrImage_2.ImageSource);
                    app.ZoomedImage.ImageSource=out;
                    app.Label.Text="";
                    else
                        
                        app.Label.Text="Enter even Number :)";
                    end
            end
        end

        % Value changed function: Images_2
        function Images_2ValueChanged(app, event)
            value = app.Images_2.Value;
            app.OrImage_2.ImageSource=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ZoomingTab = uitab(app.TabGroup);
            app.ZoomingTab.Title = 'Zooming';
            app.Zoom = uipanel(app.ZoomingTab);
            app.Zoom.Title = 'Zoomed Image';
            app.ZoomedImage = uiimage(app.Zoom);
            app.Original_2 = uipanel(app.ZoomingTab);
            app.Original_2.Title = 'Origanl Image';
            app.OrImage_2 = uiimage(app.Original_2);
            app.OrImage_2.ImageSource = 'pout.jpg';
            app.FactorEditFieldLabel = uilabel(app.ZoomingTab);
            app.FactorEditFieldLabel.Text = 'Factor';
            app.FactorEditField = uieditfield(app.ZoomingTab, 'numeric');
            app.AlgorithmsListBoxLabel_2 = uilabel(app.ZoomingTab);
            app.AlgorithmsListBoxLabel_2.Text = 'Algorithms';
            app.AlgorithmsListBoxpp_2 = uilistbox(app.ZoomingTab);
            app.AlgorithmsListBoxpp_2.Items = {'K-times', 'Replication', 'ZeroHold'};
            app.AlgorithmsListBoxpp_2.Value = 'K-times';
            app.performButton_2 = uibutton(app.ZoomingTab, 'push');
            app.performButton_2.ButtonPushedFcn = createCallbackFcn(app, @performButton_2Pushed, true);
            app.performButton_2.Text = 'perform';
            app.ImagesLabel_2 = uilabel(app.ZoomingTab);
            app.ImagesLabel_2.Text = 'Images';
            app.Images_2 = uilistbox(app.ZoomingTab);
            app.Images_2.Items = {'pout.jpg', 'cameraman.jpg'};
            app.Images_2.ValueChangedFcn = createCallbackFcn(app, @Images_2ValueChanged, true);
            app.Images_2.Value = 'pout.jpg';
            app.Label = uilabel(app.ZoomingTab);
            app.Label.Text = {''; ''};
            app.HistogramsTab = uitab(app.TabGroup);
            app.HistogramsTab.Title = 'Histograms';
            app.AlgorithmsListBoxLabel = uilabel(app.HistogramsTab);
            app.AlgorithmsListBoxLabel.Text = 'Algorithms';
            app.AlgorithmsListBoxpp = uilistbox(app.HistogramsTab);
            app.AlgorithmsListBoxpp.Items = {'Brightness Histogram', 'Equalization Histogram', 'Streching Histohram'};
            app.AlgorithmsListBoxpp.Value = 'Brightness Histogram';
            app.performButton = uibutton(app.HistogramsTab, 'push');
            app.performButton.ButtonPushedFcn = createCallbackFcn(app, @performButtonPushed, true);
            app.performButton.Text = 'perform';
            app.HisOriginal = uipanel(app.HistogramsTab);
            app.HisOriginal.Title = 'Original Imape Histgram';
            app.UIAxes = uiaxes(app.HisOriginal);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Orignal = uipanel(app.HistogramsTab);
            app.Orignal.Title = 'Original Imape';
            app.OrImage = uiimage(app.Orignal);
            app.OrImage.ImageSource = 'pout.jpg';
            app.HisModified = uipanel(app.HistogramsTab);
            app.HisModified.Title = 'Modified Imape Histgram';
            app.UIAxes_2 = uiaxes(app.HisModified);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Modified = uipanel(app.HistogramsTab);
            app.Modified.Title = 'Modified Imape ';
            app.ModifiedImage = uiimage(app.Modified);
            app.ImagesLabel = uilabel(app.HistogramsTab);
            app.ImagesLabel.Text = 'Images';
            app.Images = uilistbox(app.HistogramsTab);
            app.Images.Items = {'pout.jpg', 'cameraman.jpg'};
            app.Images.ValueChangedFcn = createCallbackFcn(app, @ImagesValueChanged, true);
            app.Images.Value = 'pout.jpg';
            app.BrightnessEditField_2Label = uilabel(app.HistogramsTab);
            app.BrightnessEditField_2Label.Text = 'Brightness';
            app.BrightnessEditField = uieditfield(app.HistogramsTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end